classdef CI_LIF_Splitter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CILIFSPlitterUIFigure       matlab.ui.Figure
        ProgressBar2                wt.ProgressBar
        ButtonMaxProj               matlab.ui.control.StateButton
        SliderTiles                 matlab.ui.control.Slider
        SliderT                     matlab.ui.control.Slider
        SliderZ                     matlab.ui.control.Slider
        PreviewDropDown             matlab.ui.control.DropDown
        PreviewDropDownLabel        matlab.ui.control.Label
        LogBox                      matlab.ui.control.ListBox
        ExpandAndSelectButton       matlab.ui.control.Button
        ExpandNoneButton            matlab.ui.control.Button
        ExpandAllButton             matlab.ui.control.Button
        MChanMinMaxScalingCheckBox  matlab.ui.control.CheckBox
        QualitySpinner              matlab.ui.control.Spinner
        ButtonGroup                 matlab.ui.container.ButtonGroup
        RGBCheckBox                 matlab.ui.control.CheckBox
        LZWlosslessButton           matlab.ui.control.RadioButton
        JPGlossyButton              matlab.ui.control.RadioButton
        CloseAllButton              matlab.ui.control.Button
        ConvertToQTIFFCheckBox      matlab.ui.control.CheckBox
        ProgressBar                 wt.ProgressBar
        HelpButton                  matlab.ui.control.Button
        RButton                     matlab.ui.control.Button
        CloseSelectedButton         matlab.ui.control.Button
        SelectFileLeica             matlab.ui.control.Button
        CButtonLIFSplit             matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        Tree                        matlab.ui.container.Tree
        Node                        matlab.ui.container.TreeNode
        UIAxesPreview               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Version='1.2'

        host=' '                     % Hostname (for debug purpose)
        
        filetype=''                 % lif or xlef
        filepath=''                 % filepath of lif/xlef file
        filename=''                 % lif/xlef file name
        filebasename=''             % lif/xlef file name without extension
        file=''                     % lif/xlef fullfile name (including filepath)
        
        Nodes  matlab.ui.container.TreeNode  % Nodes for treeview
        
        isCancelled=false           % Process is canceled (Cancel button pressed)
        
        % preview cache
        cache = struct('fileName', {}, 'basePos', {}, 'PreviewWidth', {}, 'imageData', {}, 'z', {})
        cacheSize = 50 % Set the cache size to x
        cacheIndex = 1

        AboutApp

    end
    
    properties (Access = private)
        timeSpend=0         % Accumelated time spend
        starttime           % All start time
        
        InterfaceObj        % All interface objects (that are on)
    end
    
    methods (Access = private)

        function appDisable(app)
            app.isCancelled=false;
            app.InterfaceObj=findobj(app.CILIFSPlitterUIFigure,'Enable','on');
            set(app.InterfaceObj,'Enable','off');
            app.LogBox.Enable='on';
            app.CancelButton.Enable='on';
            app.RButton.Enable='on';
            app.ButtonGroup.Enable='on';
        end
        
        function appEnable(app)
            app.isCancelled=false;
            set(app.InterfaceObj,'Enable','on');
        end
        
        function ReadLeicaLIF(app)
            app.Tree.Enable='off';
            %app.Node.delete
            fileID = fopen(app.file,'r','n','UTF-8');
            testvalue=fread(fileID,1,'int32');
            if testvalue~=112; return; end 
            XMLContentLenght=fread(fileID,1,'int32');
            testvalue=fread(fileID,1,'uint8');
            if testvalue~=42; return; end 
            testvalue=fread(fileID,1,'int32');
            % XMLObjDescriptionUTF16=reshape(fread(fileID,2*testvalue,'*char'),1,testvalue*2);
            % XMLObjDescription=cfUnicode2ascii(XMLObjDescriptionUTF16);
            XMLObjDescriptionUTF16=fread(fileID,testvalue,'uint16');
            XMLObjDescription=char(XMLObjDescriptionUTF16)'; %#ok<FREAD>
            
            % Uncomment for debugging
            % fidx=fopen('c:\testread.xml','w');
            % fwrite(fidx,XMLObjDescription);
            % fclose(fidx);
            % 
            XMLObjDescription=regexprep(XMLObjDescription,'</',[newline '</']);
            xmlElements=cfExtractElementContents(XMLObjDescription);
            
            %Read memory blocks
            lifinfo=struct;
            tel=0;
            while ~feof(fileID)
                tel=tel+1;
                testvalue=fread(fileID,1,'int32');
                if feof(fileID); break; end
                if testvalue ~=112; return; end
                BinContentLenght=fread(fileID,1,'int32');
                testvalue=fread(fileID,1,'uint8');
                if testvalue~=42; return; end 
                MemorySize=fread(fileID,1,'int64');
                testvalue=fread(fileID,1,'uint8');
                if testvalue~=42; return; end 
                testvalue=fread(fileID,1,'int32');
                sBlockID=cfUnicode2ascii(reshape(fread(fileID,2*testvalue,'*char'),1,testvalue*2));
                lifinfo(tel).BlockID=sBlockID;
                lifinfo(tel).MemorySize=MemorySize;
                lifinfo(tel).Position=ftell(fileID);
                if MemorySize>0; fseek(fileID,MemorySize,0); end
            end
            fclose(fileID);

            setLog(app,'Opening LIF-File');
            s=xml2structstring(['<?xml version="1.0" encoding="ISO-8859-1"?>' XMLObjDescription]);
            app.Node = uitreenode(app.Tree,'Text',app.filename,'NodeData',fileparts(app.file), 'Icon','LAS-X.png','Tag','File');
            setLog(app,'LAS-X LIF File Opened');
            cnode=0;
            elements=numel(s.LMSDataContainerHeader.Element.Children.Element);
            setLog(app,[num2str(elements) ' element nodes found']);
            for k = 1:elements
                cnode=cnode+1;
                if elements==1
                    thiselement=s.LMSDataContainerHeader.Element.Children.Element;
                else
                    thiselement=s.LMSDataContainerHeader.Element.Children.Element{k};
                end
                MemoryBlockID=thiselement.Memory.Attributes.MemoryBlockID;
                lifinfoindex=find(strcmpi({lifinfo.BlockID},MemoryBlockID)==1);
                MemorySize=str2double(thiselement.Memory.Attributes.Size);
                name=thiselement.Attributes.Name;
                lifinfo(lifinfoindex).filetype=app.filetype;
                lifinfo(lifinfoindex).LIFFile=app.file;
                lifinfo(lifinfoindex).name=name;
                ix=cellfun(@(x) contains(x, MemoryBlockID), xmlElements);
                ix=cfKeepLastOne(ix);
                lifinfo(lifinfoindex).xmlElement=xmlElements{ix};
                if MemorySize==0 && ~contains(name,'iomanagerconfiguation', 'IgnoreCase', true) && ~contains(name,'environmentalgraph', 'IgnoreCase', true)
                    app.Nodes(cnode)=uitreenode(app.Node,'Text',name,'NodeData',name,'Icon','folder.png','Tag','Folder');
                    elements1=numel(thiselement.Children.Element);
                    cnode1=cnode;
                    for k1 = 1:elements1
                        cnode=cnode+1;
                        if elements1==1
                            thiselement1 = thiselement.Children.Element;
                        else
                            thiselement1 = thiselement.Children.Element{k1};
                        end
                        MemoryBlockID=thiselement1.Memory.Attributes.MemoryBlockID;
                        lifinfoindex=find(strcmpi({lifinfo.BlockID},MemoryBlockID)==1);
                        MemorySize=str2double(thiselement1.Memory.Attributes.Size);
                        name1=thiselement1.Attributes.Name;
                        lifinfo(lifinfoindex).filetype=app.filetype;
                        lifinfo(lifinfoindex).LIFFile=app.file;
                        lifinfo(lifinfoindex).name=name1;
                        ix=cellfun(@(x) contains(x, MemoryBlockID), xmlElements);
                        ix=cfKeepLastOne(ix);
                        lifinfo(lifinfoindex).xmlElement=xmlElements{ix};
                        if MemorySize==0 && ~contains(name,'iomanagerconfiguation', 'IgnoreCase', true)
                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode1),'Text',name1,'NodeData',name1,'Icon','folder.png','Tag','Folder');
                            elements2=numel(thiselement1.Children.Element);
                            cnode2=cnode;
                            for k2 = 1:elements2
                                cnode=cnode+1;
                                if elements2==1
                                    thiselement2 = thiselement1.Children.Element;
                                else
                                    thiselement2 = thiselement1.Children.Element{k2};
                                end
                                MemoryBlockID=thiselement2.Memory.Attributes.MemoryBlockID;
                                lifinfoindex=find(strcmpi({lifinfo.BlockID},MemoryBlockID)==1);
                                MemorySize=str2double(thiselement2.Memory.Attributes.Size);
                                name2=thiselement2.Attributes.Name;
                                lifinfo(lifinfoindex).name=name2;
                                ix=cellfun(@(x) contains(x, MemoryBlockID), xmlElements);
                                ix=cfKeepLastOne(ix);
                                lifinfo(lifinfoindex).xmlElement=xmlElements{ix};
                                if MemorySize==0 && ~contains(name,'iomanagerconfiguation', 'IgnoreCase', true)
                                    app.Nodes(cnode)=uitreenode(app.Nodes(cnode2),'Text',name2,'NodeData',name2,'Icon','folder.png','Tag','Folder');
                                    elements3=numel(thiselement2.Children.Element);
                                    cnode3=cnode;
                                    for k3 = 1:elements3
                                        cnode=cnode+1;
                                        if elements3==1
                                            thiselement3 = thiselement2.Children.Element;
                                        else
                                            thiselement3 = thiselement2.Children.Element{k3};
                                        end
                                        MemoryBlockID=thiselement3.Memory.Attributes.MemoryBlockID;
                                        lifinfoindex=find(strcmpi({lifinfo.BlockID},MemoryBlockID)==1);
                                        MemorySize=str2double(thiselement3.Memory.Attributes.Size);
                                        name3=thiselement3.Attributes.Name;
                                        lifinfo(lifinfoindex).name=name3;
                                        ix=cellfun(@(x) contains(x, MemoryBlockID), xmlElements);
                                        ix=cfKeepLastOne(ix);
                                        lifinfo(lifinfoindex).xmlElement=xmlElements{ix};
                                        if MemorySize==0 && ~contains(name,'iomanagerconfiguation', 'IgnoreCase', true)
                                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode3),'Text',name3,'NodeData',name3,'Icon','folder.png','Tag','Folder');
                                        elseif ~contains(name3,'iomanagerconfiguation', 'IgnoreCase', true) && ~contains(name3,'environmentalgraph', 'IgnoreCase', true)
                                            lifinfo(lifinfoindex).filetype=app.filetype;
                                            lifinfo(lifinfoindex).LIFFile=app.file;
                                            lifinfo(lifinfoindex).name=name3;
                                            if isfield(thiselement3.Data,'Image') 
                                                lifinfo(lifinfoindex).datatype='Image';
                                                lifinfo(lifinfoindex).Image=thiselement3.Data.Image;
                                            else
                                                lifinfo(lifinfoindex).datatype='unknown';
                                            end
                                            lifinfo(lifinfoindex).Parent=[app.Nodes(cnode3).Parent.Parent.Text '_' app.Nodes(cnode3).Parent.Text '_' app.Nodes(cnode3).Text];
                                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode3),'Text',name3,'NodeData',{name3, lifinfo(lifinfoindex)},'Icon','image.png','Tag',lifinfo(lifinfoindex).datatype);
                                        else
                                            cnode=cnode-1;
                                        end
                                    end
                                elseif ~contains(name2,'iomanagerconfiguation', 'IgnoreCase', true) && ~contains(name2,'environmentalgraph', 'IgnoreCase', true)
                                    lifinfo(lifinfoindex).filetype=app.filetype;
                                    lifinfo(lifinfoindex).LIFFile=app.file;
                                    lifinfo(lifinfoindex).name=name2;
                                    if isfield(thiselement2.Data,'Image') 
                                        lifinfo(lifinfoindex).datatype='Image';
                                        lifinfo(lifinfoindex).Image=thiselement2.Data.Image;
                                    else
                                        lifinfo(lifinfoindex).datatype='unknown';
                                    end
                                    lifinfo(lifinfoindex).Parent=[app.Nodes(cnode2).Parent.Text '_' app.Nodes(cnode2).Text];
                                    app.Nodes(cnode)=uitreenode(app.Nodes(cnode2),'Text',name2,'NodeData',{name2, lifinfo(lifinfoindex)},'Icon',cfGetimIcon(app,name2,cnode,lifinfo(lifinfoindex)),'Tag',lifinfo(lifinfoindex).datatype);
                                else
                                    cnode=cnode-1;
                                end
                            end
                        elseif ~contains(name1,'iomanagerconfiguation', 'IgnoreCase', true) && ~contains(name1,'environmentalgraph', 'IgnoreCase', true)
                            if isfield(thiselement1.Data,'Image') 
                                lifinfo(lifinfoindex).datatype='Image';
                                lifinfo(lifinfoindex).Image=thiselement1.Data.Image;
                            else
                                lifinfo(lifinfoindex).datatype='unknown';
                            end
                            lifinfo(lifinfoindex).Parent=app.Nodes(cnode1).Text;
                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode1),'Text',name1,'NodeData',{name1, lifinfo(lifinfoindex)},'Icon','image.png','Tag',lifinfo(lifinfoindex).datatype);
                        else
                            cnode=cnode-1;
                        end
                    end
                elseif ~contains(name,'iomanagerconfiguation', 'IgnoreCase', true) && ~contains(name,'environmentalgraph', 'IgnoreCase', true)
                    if isfield(thiselement.Data,'Image') 
                        lifinfo(lifinfoindex).datatype='Image';
                        lifinfo(lifinfoindex).Image=thiselement.Data.Image;
                    else
                        lifinfo(lifinfoindex).datatype='unknown';
                    end
                    lifinfo(lifinfoindex).Parent='';
                    app.Nodes(cnode)=uitreenode(app.Node,'Text',name,'NodeData',{name, lifinfo(lifinfoindex)},'Icon','image.png','Tag',lifinfo(lifinfoindex).datatype);
                else
                    cnode=cnode-1;
                end
            end
            
            setLog(app,'Ready reading LIF file');
            app.Tree.Enable='on';
        end    
    
        function ReadLeicaXLEF(app)
            app.Tree.Enable='off';            
            xDoc = xmlread(app.file);
            app.Node = uitreenode(app.Tree,'Text',app.filename,'NodeData',fileparts(app.file), 'Icon','LAS-X.png','Tag','File');
            setLog(app,'Las-X XLEF File Opened');
            xmlInfo = xDoc.getElementsByTagName('Reference');
            cnode=0;
            setLog(app,[num2str(xmlInfo.getLength-1) ' reference nodes found']);
            for k = 0:xmlInfo.getLength-1
                cnode=cnode+1;
                thisInfo = xmlInfo.item(k);
                ref=getXLfile(app, char(thisInfo.getAttribute('File')),'');
                [~,name,ext]=fileparts(ref);
                lifinfo.name=name;
                lifinfo.XLEFFile=app.file;
                lifinfo.filetype=app.filetype;
                lifinfo.XLIFFile=ref;
                lifinfo.LIFFile=ref;
                lifinfo.LOFFile=getLOFfile(app, ref);
                [lifinfo.MemorySize, lifinfo.xmlElement]=cfReadLOFInfo(lifinfo);
                if strcmpi(ext,'.xlcf')
                    app.Nodes(cnode)=uitreenode(app.Node,'Text',name,'NodeData',ref,'Icon','folder.png','Tag','Folder');
                    xDoc1 = xmlread(ref);
                    xmlInfo1 = xDoc1.getElementsByTagName('Reference');
                    cnode1=cnode;
                    for k1 = 0:xmlInfo1.getLength-1
                        cnode=cnode+1;
                        thisInfo1 = xmlInfo1.item(k1);
                        ref1=getXLfile(app, char(thisInfo1.getAttribute('File')),ref);
                        [~,name1,ext1]=fileparts(ref1);
                        lifinfo.name=name1;
                        lifinfo.XLEFFile=app.file;
                        lifinfo.filetype=app.filetype;
                        lifinfo.XLIFFile=ref1;
                        lifinfo.LIFFile=ref1;
                        lifinfo.LOFFile=getLOFfile(app, ref1);
                        [lifinfo.MemorySize, lifinfo.xmlElement]=cfReadLOFInfo(lifinfo);
                        if strcmpi(ext1,'.xlcf')
                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode1),'Text',name1,'NodeData',ref1,'Icon','folder.png','Tag','Folder');
                            xDoc2 = xmlread(ref1);
                            xmlInfo2 = xDoc2.getElementsByTagName('Reference');
                            cnode2=cnode;
                            for k2 = 0:xmlInfo2.getLength-1
                                cnode=cnode+1;
                                thisInfo2 = xmlInfo2.item(k2);
                                ref2=getXLfile(app, char(thisInfo2.getAttribute('File')),ref1);
                                [~,name2,ext2]=fileparts(ref2);
                                lifinfo.name=name2;
                                lifinfo.XLEFFile=app.file;
                                lifinfo.filetype=app.filetype;
                                lifinfo.XLIFFile=ref2;
                                lifinfo.LIFFile=ref2;
                                lifinfo.LOFFile=getLOFfile(app, ref2);
                                [lifinfo.MemorySize, lifinfo.xmlElement]=cfReadLOFInfo(lifinfo);
                                if strcmpi(ext2,'.xlcf')
                                    app.Nodes(cnode)=uitreenode(app.Nodes(cnode2),'Text',name2,'NodeData',ref2,'Icon','folder.png','Tag','Folder');
                                    xDoc3 = xmlread(ref2);
                                    xmlInfo3 = xDoc3.getElementsByTagName('Reference');
                                    cnode3=cnode;
                                    for k3 = 0:xmlInfo3.getLength-1
                                        cnode=cnode+1;
                                        thisInfo3 = xmlInfo3.item(k3);
                                        ref3=getXLfile(app, char(thisInfo3.getAttribute('File')),ref2);
                                        [~,name3,ext3]=fileparts(ref3);
                                        lifinfo.name=name3;
                                        lifinfo.XLEFFile=app.file;
                                        lifinfo.filetype=app.filetype;
                                        lifinfo.XLIFFile=ref3;
                                        lifinfo.LIFFile=ref3;
                                        lifinfo.LOFFile=getLOFfile(app, ref3);
                                        [lifinfo.MemorySize, lifinfo.xmlElement]=cfReadLOFInfo(lifinfo);
                                        if strcmpi(ext3,'.xlcf')
                                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode3),'Text',name3,'NodeData',ref3,'Icon','folder.png','Tag','Folder');
                                        elseif ~contains(name3,'iomanagerconfiguation', 'IgnoreCase', true) && ~contains(name3,'environmentalgraph', 'IgnoreCase', true)
                                            s=xml2struct(lifinfo.XLIFFile);
                                            lifinfo.Image=s.LMSDataContainerHeader.Element.Data.Image;
                                            lifinfo.datatype='Image';
                                            lifinfo.Parent=[app.Nodes(cnode3).Parent.Parent.Text '_' app.Nodes(cnode3).Parent.Text '_' app.Nodes(cnode3).Text];
                                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode3),'Text',name3,'NodeData',{ref3,lifinfo},'Icon','image.png','Tag','Image');
                                        else
                                            cnode=cnode-1;
                                        end
                                    end
                                elseif ~contains(name2,'iomanagerconfiguation', 'IgnoreCase', true) && ~contains(name2,'environmentalgraph', 'IgnoreCase', true)
                                    s=xml2struct(lifinfo.XLIFFile);
                                    lifinfo.Image=s.LMSDataContainerHeader.Element.Data.Image;
                                    lifinfo.datatype='Image';
                                    lifinfo.Parent=[app.Nodes(cnode2).Parent.Text '_' app.Nodes(cnode2).Text];
                                    app.Nodes(cnode)=uitreenode(app.Nodes(cnode2),'Text',name2,'NodeData',{ref2,lifinfo},'Icon','image.png','Tag','Image');
                                else
                                    cnode=cnode-1;
                                end
                            end
                        elseif ~contains(name1,'iomanagerconfiguation', 'IgnoreCase', true) && ~contains(name1,'environmentalgraph', 'IgnoreCase', true)
                            s=xml2struct(lifinfo.XLIFFile);
                            lifinfo.Image=s.LMSDataContainerHeader.Element.Data.Image;
                            lifinfo.datatype='Image';
                            lifinfo.Parent=app.Nodes(cnode1).Text;
                            app.Nodes(cnode)=uitreenode(app.Nodes(cnode1),'Text',name1,'NodeData',{ref1,lifinfo},'Icon','image.png','Tag','Image');
                        else
                            cnode=cnode-1;
                        end
                    end
                elseif ~contains(name,'iomanagerconfiguation', 'IgnoreCase', true) && ~contains(name,'environmentalgraph', 'IgnoreCase', true)
                    s=xml2struct(lifinfo.XLIFFile);
                    lifinfo.Image=s.LMSDataContainerHeader.Element.Data.Image;
                    lifinfo.datatype='Image';
                    lifinfo.Parent='';
                    app.Nodes(cnode)=uitreenode(app.Node,'Text',name,'NodeData',{ref,lifinfo},'Icon','image.png','Tag','Image');
                else
                    cnode=cnode-1;
                end
            end
            setLog(app,'Ready reading XLEF file');
            app.Tree.Enable='off';            
            %expand(app.Tree,'all');
        end
        
        function ReadLeicaLOF(app)
            app.Tree.Enable='off';
            lifinfo=struct;
            lifinfo.name=app.filebasename;
            lifinfo.filetype=app.filetype;
            lifinfo.File=app.file;
            lifinfo.LOFFile=app.file;
            lifinfo.datatype='Image';
            lifinfo.Parent='';
            [lifinfo.MemorySize, lifinfo.xmlElement]=cfReadLOFInfo(lifinfo);
            s=xml2structstring(lifinfo.xmlElement);
            lifinfo.Image=s.Data.Image;
            app.Node = uitreenode(app.Tree,'Text',app.filename,'NodeData',{app.filename, lifinfo},'Icon','image.png','Tag','ImageFile');
            setLog(app,'Ready adding file');
            app.Tree.Enable='on';
        end    

        function [XLfile]=getXLfile(app, sfile, sparent)
            %Java problem so used .NET not sfile=urldecode(replace(sfile,'+','%2b'));
            NET.addAssembly('System.Web');
            sfile=char(System.Web.HttpUtility.UrlDecode(replace(sfile,'+','%2b')));
            if ~isempty(sparent)
                i=strfind(sparent,'\');
                if contains(sfile,'..\')
                    sfile=strrep(sfile,'..\',[extractBefore(sparent,i(end-1)) '\']);
                else
                    sfile=strrep(sfile,'.\',[extractBefore(sparent,i(end)) '\']);
                end
            end
            sfile=strrep(sfile,'.\',app.filepath);
            XLfile=sfile;
        end
        
        function [LOFfile]=getLOFfile(~, sfile)
            [~, name, ~]=fileparts(sfile);
            i=strfind(sfile,'\');
            sfile=[extractBefore(sfile,i(end-1)) '\' name '.lof'];
            %MICA Bug solved?
            if ~isfile(sfile)
                sfile=replace(sfile,'\additional data\','\');
            end
            LOFfile=sfile;
        end
        
        function DoShowImage(app, lifinfo, iminfo)
            if app.PreviewDropDown.Value>0 
                cla(app.UIAxesPreview)
                app.UIAxesPreview.Toolbar.Visible = 'off';
                if iminfo.isrgb && iminfo.channels==3
                    im=LIFsplit.cfCreatePreviewRGB(app,lifinfo);                    
                    imshow(im, [], 'Parent', app.UIAxesPreview, 'InitialMagnification', 'fit', 'Border', 'tight');
                    % Get image size
                    [height, width, ~] = size(im);
                    % Set uiaxes limits to the size of the image and center
                    app.UIAxesPreview.XLim = [0 width];
                    app.UIAxesPreview.YLim = [0 height];
                    app.UIAxesPreview.XTick = [];
                    app.UIAxesPreview.YTick = [];
                    app.UIAxesPreview.XColor = 'none';
                    app.UIAxesPreview.YColor = 'none';                        
                elseif ~iminfo.isrgb  
                    im=LIFsplit.cfCreatePreviewMultiChannel(app,lifinfo);                    
                    imshow(im, [], 'Parent', app.UIAxesPreview, 'InitialMagnification', 'fit', 'Border', 'tight');
                    % Get image size
                    [height, width, ~] = size(im);
                    % Set uiaxes limits to the size of the image and center
                    app.UIAxesPreview.XLim = [0 width];
                    app.UIAxesPreview.YLim = [0 height];
                    app.UIAxesPreview.XTick = [];
                    app.UIAxesPreview.YTick = [];
                    app.UIAxesPreview.XColor = 'none';
                    app.UIAxesPreview.YColor = 'none';                        
                end
                value=app.PreviewDropDown.Value>0;
                app.SliderT.Visible=(value && str2double(app.SliderT.Tag));
                app.SliderTiles.Visible=(value && str2double(app.SliderTiles.Tag));
                app.SliderZ.Visible=(value && str2double(app.SliderZ.Tag));   
                app.SliderZ.Enable=(~app.ButtonMaxProj.Value);   
                app.ButtonMaxProj.Visible=(value && str2double(app.SliderZ.Tag));
            end
        end
        
        function callAboutApp(app)
            help;
        end
    end
    
    methods (Access = public)
        
        
        function setProgress(app, fractionComplete, sTitle)
            app.ProgressBar2.setProgress(fractionComplete, sTitle)
        end

        function setProgress2(app, fractionComplete, sTitle)
            app.ProgressBar.setProgress(fractionComplete, sTitle)
        end        
    
        function setLog(app, newlog)
            cutv=60;
            if ~isempty(newlog)
                newlog=replace(newlog,'_','_ ');
                newlog=replace(newlog,'\','\ ');
                newlog=replace(newlog,'/','/ ');
                t=datetime;
                t.Format='HH:mm:ss';
                pspace=strfind(newlog,' ');
                tc=[char(t) '> '];
                if ~isempty(pspace)
                    if pspace(1)==1
                        tc='                ';
                        cutv=60;
                    end
                end
                newLine = [tc newlog];
            else
                newLine=' ';
            end
            n=numel(app.LogBox.Items);
            newLine2='';
            if length(newLine)>cutv
                pspace=strfind(extractBefore(newLine,cutv),' ');
                if ~isempty(pspace); cutv=pspace(end); end
                if cutv>1; newLine2=extractAfter(newLine,cutv-1); end
                newLine=extractBefore(newLine,cutv);
            end
            if n<500
                app.LogBox.Items=[app.LogBox.Items(:); newLine];
            else
                app.LogBox.Items=[app.LogBox.Items(n-499:end) newLine];
            end
            app.LogBox.Value=newLine;
            scroll(app.LogBox,'bottom');
            if ~isempty(newLine2); setLog(app, newLine2); end
        end
        
        function ShowPreview(app, lifinfo, iminfo)
            if iminfo.ts>1
                app.SliderT.Value=1;
                app.SliderT.Limits=[1 iminfo.ts];
                app.SliderT.Tag='1';
            else
                app.SliderT.Tag='0';
            end
            if iminfo.tiles>1
                app.SliderTiles.Value=1;
                app.SliderTiles.Limits=[1 iminfo.tiles];
                app.SliderTiles.Tag='1';
            else
                app.SliderTiles.Tag='0';
            end
            if iminfo.zs>1
                app.SliderZ.Value=1;
                app.SliderZ.Limits=[1 iminfo.zs];
                app.SliderZ.Value=floor(iminfo.zs/2);
                app.SliderZ.Tag='1';
            else
                app.SliderZ.Tag='0';
            end
            DoShowImage(app, lifinfo, iminfo);            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            appDisable(app);
            app.Node.delete;
            app.host=getenv('COMPUTERNAME');
            app.CILIFSPlitterUIFigure.Name=['CI LIF Splitter ' app.Version];
            setLog(app,['Welcome to CI LIF Splitter ' app.Version])
            setLog(app,'Click on About for more info!')
            setProgress(app, 0,'')            
            appEnable(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.isCancelled=true;
            setLog(app,'Canceling..');
        end

        % Button pushed function: SelectFileLeica
        function SelectFileLeicaButtonPushed(app, event)
            [filenames,app.filepath]=uigetfile({'*.lif;*.xlef;*.lof','Leica LAS-X Files'},'Select LIF or XLEF/LOF File',app.filepath,'MultiSelect','on');
            if app.filepath==0
                app.filepath='';
                return;
            end
            if ~iscell(filenames)
                app.filename=filenames;
                app.file=[app.filepath app.filename];
                [~,app.filebasename,app.filetype]=fileparts(app.file);
                if strcmpi(app.filetype,'.xlef')    
                    ReadLeicaXLEF(app);
                elseif strcmpi(app.filetype,'.lif')
                    ReadLeicaLIF(app);
                elseif strcmpi(app.filetype,'.lof')
                    ReadLeicaLOF(app);
                end
            else
                for k=1:numel(filenames)
                    app.filename=filenames{k};
                    app.file=[app.filepath app.filename];
                    [~,app.filebasename,app.filetype]=fileparts(app.file);
                    if strcmpi(app.filetype,".xlef")    
                        ReadLeicaXLEF(app);
                    elseif strcmpi(app.filetype,".lif")
                        ReadLeicaLIF(app);
                    elseif strcmpi(app.filetype,".lof")
                        ReadLeicaLOF(app);
                    end       

                end
            end
            app.Tree.Enable='on';
            appEnable(app);          
        end

        % Button pushed function: CloseSelectedButton
        function CloseSelectedButtonButtonPushed(app, event)
            tel=1;
            for i=1:numel(app.Tree.SelectedNodes)
                if numel(app.Tree.SelectedNodes)>0 
                    if strcmpi(app.Tree.SelectedNodes(tel).Tag,'File') || strcmpi(app.Tree.SelectedNodes(tel).Tag,'ImageFile')
                        app.Tree.SelectedNodes(tel).delete
                    else
                        tel=tel+1;
                    end
                end
            end
            if numel(app.Tree.Children)==0
                appDisable(app);
                app.SelectFileLeica.Enable='on';
            end            
        end

        % Button pushed function: CButtonLIFSplit
        function CButtonLIFSplitPushed(app, event)
            appDisable(app);
            LIFsplit.cfProcessLIFSplit(app)
            appEnable(app);           
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if numel(selectedNodes)==1 && (strcmpi(selectedNodes(1).Tag,'Image') || strcmpi(selectedNodes(1).Tag,'ImageFile'))
                lifinfo=selectedNodes(1).NodeData{2};
                [~, ~, iminfo]=cfReadMetaData(lifinfo);
                setLog(app,' ')
                setLog(app,lifinfo.name)
                if iminfo.isrgb 
                    setLog(app,'RGB Image')
                else
                    setLog(app,[num2str(iminfo.channels) ' Channels Image'])
                end
                if iminfo.tiles>1 && iminfo.zs==1
                    app.setLog(['Tiles: ' num2str(iminfo.tiles) ', TileSize: ' num2str(iminfo.xs) 'x' num2str(iminfo.ys) ' px, ' num2str(round(iminfo.xs*iminfo.xres2)) 'x' num2str(round(iminfo.ys*iminfo.xres2)) ' µm'])  
                elseif iminfo.zs>1 && iminfo.tiles<=1
                    app.setLog(['ImageSize: ' num2str(iminfo.xs) 'x' num2str(iminfo.ys) 'x' num2str(iminfo.zs) ' px, ' num2str(round(iminfo.xs*iminfo.xres2)) 'x' num2str(round(iminfo.ys*iminfo.xres2)) 'x' num2str(round(iminfo.zs*iminfo.zres2)) ' µm'])  
                elseif iminfo.tiles>1 && iminfo.zs>1
                    app.setLog(['Tiles: ' num2str(iminfo.tiles) ', TileSize: ' num2str(iminfo.xs) 'x' num2str(iminfo.ys) 'x' num2str(iminfo.zs) ' px, ' num2str(round(iminfo.xs*iminfo.xres2)) 'x' num2str(round(iminfo.ys*iminfo.xres2)) 'x' num2str(round(iminfo.zs*iminfo.zres2)) ' µm'])  
                else
                    app.setLog(['ImageSize: ' num2str(iminfo.xs) 'x' num2str(iminfo.ys) ' px, ' num2str(round(iminfo.xs*iminfo.xres2)) 'x' num2str(round(iminfo.ys*iminfo.xres2)) ' µm'])  
                end
                setLog(app,['SizeInBytes: ' cfBytesString(lifinfo.MemorySize)])
                setLog(app,['BitsPerPixel: ' num2str(iminfo.channelResolution(1))])
                setLog(app,['Objective: ' iminfo.objective]) 
                setLog(app,['Resolution: ' num2str(round(iminfo.xres2,3)) ' µm/pixel'])
                ShowPreview(app, lifinfo, iminfo)
                % if iminfo.ts>1
                %     app.SliderT.Value=1;
                %     app.SliderT.Limits=[1 iminfo.ts];
                %     app.SliderT.Tag='1';
                % else
                %     app.SliderT.Tag='0';
                % end
                % if iminfo.tiles>1
                %     app.SliderTiles.Value=1;
                %     app.SliderTiles.Limits=[1 iminfo.tiles];
                %     app.SliderTiles.Tag='1';
                % else
                %     app.SliderTiles.Tag='0';
                % end
                % if iminfo.zs>1
                %     app.SliderZ.Value=1;
                %     app.SliderZ.Limits=[1 iminfo.zs];
                %     app.SliderZ.Value=floor(iminfo.zs/2);
                %     app.SliderZ.Tag='1';
                % else
                %     app.SliderZ.Tag='0';
                % end
                % DoShowImage(app);
            end
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            appEnable(app);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            callAboutApp(app)
            % atitle='About';
            % if isdeployed
            %     HTMLSource=[ctfroot '/CI_LIF_Splitter/' atitle '.html'];
            % else
            %     HTMLSource=[pwd '\' atitle '.html'];
            % end 
            % amessage=fileread(HTMLSource);
            % uialert(app.CILIFSPlitterUIFigure,amessage,atitle,'Icon','','Interpreter','html')            
        end

        % Callback function: ButtonGroup, ConvertToQTIFFCheckBox
        function ConvertToQTIFFCheckBoxValueChanged(app, event)
            value = app.ConvertToQTIFFCheckBox.Value;
            app.JPGlossyButton.Enable=value;
            app.LZWlosslessButton.Enable=value;
            app.QualitySpinner.Enable=(value && app.JPGlossyButton.Value);
            app.MChanMinMaxScalingCheckBox.Enable=(value && app.JPGlossyButton.Value);
            app.RGBCheckBox.Enable=(value && app.LZWlosslessButton.Value);
        end

        % Button pushed function: CloseAllButton
        function CloseAllButtonPushed(app, event)
            a=app.Tree.Children;
            a.delete;
        end

        % Button pushed function: ExpandAllButton
        function ExpandAllButtonPushed(app, event)
            expand(app.Tree,'all')
        end

        % Button pushed function: ExpandNoneButton
        function ExpandNoneButtonPushed(app, event)
            collapse(app.Tree,'all')
        end

        % Button pushed function: ExpandAndSelectButton
        function ExpandAndSelectButtonPushed(app, event)
            app.Tree.SelectedNodes=[];
            expand(app.Tree,'all');
            for ii = 1:length(app.Tree.Children)
                for jj = 1:length(app.Tree.Children(ii).Children)
                    if strcmp(app.Tree.Children(ii).Children(jj).Tag,'Image')
                        app.Tree.SelectedNodes = [app.Tree.SelectedNodes; app.Tree.Children(ii).Children(jj)];
                    end
                    for kk = 1:length(app.Tree.Children(ii).Children(jj).Children)
                        if strcmp(app.Tree.Children(ii).Children(jj).Children(kk).Tag,'Image')
                            app.Tree.SelectedNodes = [app.Tree.SelectedNodes; app.Tree.Children(ii).Children(jj).Children(kk)];
                        end
                        for ll = 1:length(app.Tree.Children(ii).Children(jj).Children(kk).Children)
                            if strcmp(app.Tree.Children(ii).Children(jj).Children(kk).Children(ll).Tag,'Image')
                                app.Tree.SelectedNodes = [app.Tree.SelectedNodes; app.Tree.Children(ii).Children(jj).Children(kk).Children(ll)];
                            end
                            for mm = 1:length(app.Tree.Children(ii).Children(jj).Children(kk).Children(ll).Children)
                                if strcmp(app.Tree.Children(ii).Children(jj).Children(kk).Children(ll).Children(mm).Tag,'Image')
                                    app.Tree.SelectedNodes = [app.Tree.SelectedNodes; app.Tree.Children(ii).Children(jj).Children(kk).Children(ll).Children(mm)];
                                end
                            end                        
                        end                        
                    end
                end
            end            
        end

        % Value changed function: PreviewDropDown
        function PreviewDropDownValueChanged(app, event)
            value = app.PreviewDropDown.Value;
            app.UIAxesPreview.Visible=(value>0);
            selectedNodes = app.Tree.SelectedNodes;
            if numel(selectedNodes)==1 && (strcmpi(selectedNodes(1).Tag,'Image') || strcmpi(selectedNodes(1).Tag,'ImageFile'))
                lifinfo=selectedNodes(1).NodeData{2};
                [~, ~, iminfo]=cfReadMetaData(lifinfo);
            else
                return
            end            
            DoShowImage(app, lifinfo, iminfo);            
        end

        % Value changed function: SliderZ
        function SliderZValueChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if numel(selectedNodes)==1 && (strcmpi(selectedNodes(1).Tag,'Image') || strcmpi(selectedNodes(1).Tag,'ImageFile'))
                lifinfo=selectedNodes(1).NodeData{2};
                [~, ~, iminfo]=cfReadMetaData(lifinfo);
            else
                return
            end            
            DoShowImage(app, lifinfo, iminfo);            
        end

        % Value changed function: SliderT
        function SliderTValueChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if numel(selectedNodes)==1 && (strcmpi(selectedNodes(1).Tag,'Image') || strcmpi(selectedNodes(1).Tag,'ImageFile'))
                lifinfo=selectedNodes(1).NodeData{2};
                [~, ~, iminfo]=cfReadMetaData(lifinfo);
            else
                return
            end            
            DoShowImage(app, lifinfo, iminfo);            
        end

        % Value changed function: ButtonMaxProj
        function ButtonMaxProjValueChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if numel(selectedNodes)==1 && (strcmpi(selectedNodes(1).Tag,'Image') || strcmpi(selectedNodes(1).Tag,'ImageFile'))
                lifinfo=selectedNodes(1).NodeData{2};
                [~, ~, iminfo]=cfReadMetaData(lifinfo);
            else
                return
            end            
            DoShowImage(app, lifinfo, iminfo);            
        end

        % Value changed function: SliderTiles
        function SliderTilesValueChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if numel(selectedNodes)==1 && (strcmpi(selectedNodes(1).Tag,'Image') || strcmpi(selectedNodes(1).Tag,'ImageFile'))
                lifinfo=selectedNodes(1).NodeData{2};
                [~, ~, iminfo]=cfReadMetaData(lifinfo);
            else
                return
            end            
            DoShowImage(app, lifinfo, iminfo);            
        end

        % Close request function: CILIFSPlitterUIFigure
        function CILIFSPlitterUIFigureCloseRequest(app, event)
            delete(app)
            try
                delete(app.AboutApp)
            catch
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CILIFSPlitterUIFigure = uifigure('Visible', 'off');
            app.CILIFSPlitterUIFigure.Name = 'CI LIF Splitter';
            app.CILIFSPlitterUIFigure.Icon = 'LIFSplittericon48x48.png';
            app.CILIFSPlitterUIFigure.CloseRequestFcn = createCallbackFcn(app, @CILIFSPlitterUIFigureCloseRequest, true);
            app.UIAxesPreview = uiaxes(app.CILIFSPlitterUIFigure);
            app.UIAxesPreview.XTick = [];
            app.UIAxesPreview.XTickLabel = '';
            app.UIAxesPreview.YTick = [];
            app.UIAxesPreview.YTickLabel = '';
            app.Tree = uitree(app.CILIFSPlitterUIFigure);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.NodeData = 'Root';
            app.Node.Text = 'Node';
            app.CancelButton = uibutton(app.CILIFSPlitterUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = 'Cancel (2).png';
            app.CancelButton.Text = 'Cancel';
            app.CButtonLIFSplit = uibutton(app.CILIFSPlitterUIFigure, 'push');
            app.CButtonLIFSplit.ButtonPushedFcn = createCallbackFcn(app, @CButtonLIFSplitPushed, true);
            app.CButtonLIFSplit.Icon = fullfile(pathToMLAPP, 'Save.png');
            app.CButtonLIFSplit.Text = 'Split Selection to Separate LIF/QTIFF Files';
            app.SelectFileLeica = uibutton(app.CILIFSPlitterUIFigure, 'push');
            app.SelectFileLeica.ButtonPushedFcn = createCallbackFcn(app, @SelectFileLeicaButtonPushed, true);
            app.SelectFileLeica.Icon = 'plus2.png';
            app.SelectFileLeica.Text = 'Add Leica LAS-X File (LIF or XLEF/LOF) to Tree';
            app.CloseSelectedButton = uibutton(app.CILIFSPlitterUIFigure, 'push');
            app.CloseSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @CloseSelectedButtonButtonPushed, true);
            app.CloseSelectedButton.Icon = 'min.png';
            app.CloseSelectedButton.Text = 'Close Selected';
            app.RButton = uibutton(app.CILIFSPlitterUIFigure, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.HelpButton = uibutton(app.CILIFSPlitterUIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Icon = fullfile(pathToMLAPP, 'Help symbol -bw.png');
            app.HelpButton.Text = 'Help';
            app.ProgressBar = wt.ProgressBar(app.CILIFSPlitterUIFigure);
            app.ConvertToQTIFFCheckBox = uicheckbox(app.CILIFSPlitterUIFigure);
            app.ConvertToQTIFFCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConvertToQTIFFCheckBoxValueChanged, true);
            app.ConvertToQTIFFCheckBox.Text = ' Convert 2D and 3D (max projection) Images to QPTIFF';
            app.CloseAllButton = uibutton(app.CILIFSPlitterUIFigure, 'push');
            app.CloseAllButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllButtonPushed, true);
            app.CloseAllButton.Icon = 'min.png';
            app.CloseAllButton.Text = 'Close All';
            app.ButtonGroup = uibuttongroup(app.CILIFSPlitterUIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConvertToQTIFFCheckBoxValueChanged, true);
            app.JPGlossyButton = uiradiobutton(app.ButtonGroup);
            app.JPGlossyButton.Enable = 'off';
            app.JPGlossyButton.Text = 'JPG (lossy 8-bits)  Q:';
            app.LZWlosslessButton = uiradiobutton(app.ButtonGroup);
            app.LZWlosslessButton.Enable = 'off';
            app.LZWlosslessButton.Text = 'LZW (lossless)';
            app.LZWlosslessButton.Value = true;
            app.RGBCheckBox = uicheckbox(app.ButtonGroup);
            app.RGBCheckBox.Enable = 'off';
            app.RGBCheckBox.Tooltip = {'When using JPG compression Multi Channel Images are scaled between Min and Max Value when bitdepth of original > 8, RGB Images are rescaled between 0 and max intensity.'};
            app.RGBCheckBox.Text = 'Convert 12bit RGB to 8bit RGB';
            app.RGBCheckBox.Value = true;
            app.QualitySpinner = uispinner(app.CILIFSPlitterUIFigure);
            app.QualitySpinner.Limits = [30 100];
            app.QualitySpinner.Enable = 'off';
            app.QualitySpinner.Tooltip = {'JPG Quality'};
            app.QualitySpinner.Value = 80;
            app.MChanMinMaxScalingCheckBox = uicheckbox(app.CILIFSPlitterUIFigure);
            app.MChanMinMaxScalingCheckBox.Enable = 'off';
            app.MChanMinMaxScalingCheckBox.Tooltip = {'When using JPG compression Multi Channel Images are scaled between Min and Max Value when bitdepth of original > 8, RGB Images are rescaled between 0 and max intensity.'};
            app.MChanMinMaxScalingCheckBox.Text = 'MChan MinMax Scaling';
            app.MChanMinMaxScalingCheckBox.Value = true;
            app.ExpandAllButton = uibutton(app.CILIFSPlitterUIFigure, 'push');
            app.ExpandAllButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandAllButtonPushed, true);
            app.ExpandAllButton.Icon = 'Expand.png';
            app.ExpandAllButton.Text = '';
            app.ExpandNoneButton = uibutton(app.CILIFSPlitterUIFigure, 'push');
            app.ExpandNoneButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandNoneButtonPushed, true);
            app.ExpandNoneButton.Icon = 'Collapse.png';
            app.ExpandNoneButton.Text = '';
            app.ExpandAndSelectButton = uibutton(app.CILIFSPlitterUIFigure, 'push');
            app.ExpandAndSelectButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandAndSelectButtonPushed, true);
            app.ExpandAndSelectButton.Icon = fullfile(pathToMLAPP, 'tree--plus.png');
            app.ExpandAndSelectButton.Text = '';
            app.LogBox = uilistbox(app.CILIFSPlitterUIFigure);
            app.LogBox.Items = {};
            app.LogBox.Value = {};
            app.PreviewDropDownLabel = uilabel(app.CILIFSPlitterUIFigure);
            app.PreviewDropDownLabel.Text = 'Preview:';
            app.PreviewDropDown = uidropdown(app.CILIFSPlitterUIFigure);
            app.PreviewDropDown.Items = {'Off', 'Very Low Quality (fastest)', 'Low Quality (fast)', 'Medium Quality (slow)', 'High Quality (slower)', 'Ultra High Quality (slowest)'};
            app.PreviewDropDown.ItemsData = [0 32 64 128 256 512];
            app.PreviewDropDown.ValueChangedFcn = createCallbackFcn(app, @PreviewDropDownValueChanged, true);
            app.PreviewDropDown.Value = 0;
            app.SliderZ = uislider(app.CILIFSPlitterUIFigure);
            app.SliderZ.MajorTicks = [];
            app.SliderZ.MajorTickLabels = {''};
            app.SliderZ.ValueChangedFcn = createCallbackFcn(app, @SliderZValueChanged, true);
            app.SliderZ.MinorTicks = [];
            app.SliderZ.Visible = 'off';
            app.SliderZ.Tooltip = {'Z-Slices'};
            app.SliderT = uislider(app.CILIFSPlitterUIFigure);
            app.SliderT.MajorTicks = [];
            app.SliderT.MajorTickLabels = {''};
            app.SliderT.ValueChangedFcn = createCallbackFcn(app, @SliderTValueChanged, true);
            app.SliderT.MinorTicks = [];
            app.SliderT.Visible = 'off';
            app.SliderT.Tooltip = {'Time'};
            app.SliderTiles = uislider(app.CILIFSPlitterUIFigure);
            app.SliderTiles.MajorTicks = [];
            app.SliderTiles.MajorTickLabels = {''};
            app.SliderTiles.ValueChangedFcn = createCallbackFcn(app, @SliderTilesValueChanged, true);
            app.SliderTiles.MinorTicks = [];
            app.SliderTiles.Visible = 'off';
            app.SliderTiles.Tooltip = {'Tiles'};
            app.ButtonMaxProj = uibutton(app.CILIFSPlitterUIFigure, 'state');
            app.ButtonMaxProj.ValueChangedFcn = createCallbackFcn(app, @ButtonMaxProjValueChanged, true);
            app.ButtonMaxProj.Visible = 'off';
            app.ButtonMaxProj.Tooltip = {'Max Projection'};
            app.ButtonMaxProj.Icon = fullfile(pathToMLAPP, 'pictures-stack.png');
            app.ButtonMaxProj.Text = '';
            app.ProgressBar2 = wt.ProgressBar(app.CILIFSPlitterUIFigure);
            % Show the figure after all components are created
            app.CILIFSPlitterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CI_LIF_Splitter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CILIFSPlitterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CILIFSPlitterUIFigure)
        end
    end
end