classdef help < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HelpUIFigure  matlab.ui.Figure
        CloseButton   matlab.ui.control.Button
        HTML          matlab.ui.control.HTML
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.HelpUIFigure, 'center');
            if isdeployed
                HTMLSource=[ctfroot '\CI_LIF_Splitter\about.html'];
            else
                HTMLSource=[pwd '\about.html'];
            end 
            app.HTML.HTMLSource=fileread(HTMLSource);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app.HelpUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HelpUIFigure = uifigure('Visible', 'off');
            app.HelpUIFigure.Name = 'Help';
            app.HelpUIFigure.Icon = fullfile(pathToMLAPP, 'Help symbol -bw.png');
            app.HTML = uihtml(app.HelpUIFigure);
            app.CloseButton = uibutton(app.HelpUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.HelpUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = help

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HelpUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HelpUIFigure)
        end
    end
end